classdef InerterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartDataCollectionButton       matlab.ui.control.StateButton
        InerterMassEditField            matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        InnerRadiiEditField             matlab.ui.control.NumericEditField
        InnerRadiimEditFieldLabel       matlab.ui.control.Label
        OuterRadiiEditField             matlab.ui.control.NumericEditField
        OuterRadiimEditFieldLabel       matlab.ui.control.Label
        numGearsEditField               matlab.ui.control.NumericEditField
        ofGearsEditFieldLabel           matlab.ui.control.Label
        ExperimentalInertanceEditField  matlab.ui.control.NumericEditField
        ExperimentalInertanceLabel      matlab.ui.control.Label
        PerformInertanceCalculationButton  matlab.ui.control.Button
        CalculatedInertanceEditField    matlab.ui.control.NumericEditField
        CalculatedInertancekgEditFieldLabel  matlab.ui.control.Label
        ForceMagnitudeEditField         matlab.ui.control.NumericEditField
        ForceMagnitudeEditFieldLabel    matlab.ui.control.Label
        AccelerationMagnitudeEditField  matlab.ui.control.NumericEditField
        AccelerationMagnitudeLabel      matlab.ui.control.Label
        ForcePlot                       matlab.ui.control.UIAxes
        AccPlot                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        forceDataArray % Array storing force data from load cell
        accDataArray % Array storing accelerometer data
        accTimeArray % Array storing time data from accelerometer/daq
        forceTimeArray % Array storing time data from arduino load cell
        dataCount % Array storing data counts from Arduino force data
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PerformInertanceCalculationButton
        function CalculateInertance(app, event)
            % Pulling current data displayed from the graphs
            forceLine = findobj(app.ForcePlot, 'Type', 'Line');
            accLine = findobj(app.AccPlot, 'Type', 'Line');

            % Only generates experimental results if data is present
            if ~isempty(forceLine) && ~isempty(accLine)
                forceData = forceLine(1).YData;
                accData = accLine(1).YData;

                % Frequency Domain Analysis
                forceFD = fft(forceData);       % Force data in frequency domain
                accFD = fft(accData);           % Acceleration data in frequency domain
                F_exp = max(forceFD);           % Experimentally determined F value
                a_exp = max(accFD);             % Experimentally determined a value

                % Calculating experimental inertance value
                b_exp = F_exp/a_exp;
            else
                b_exp = 0;
            end

            % Calculating theoretical inertance value
            R = app.OuterRadiiEditField.Value;        % Outer gear radius (m)
            r = app.InnerRadiiEditField.Value;        % Inner gear radius (m)
            m = app.InerterMassEditField.Value;       % Mass of inerter (kg)
            n = app.numGearsEditField.Value;          % Number of gears in train
            b_theo = (R^2/r^2)^n * m;

            % Displaying inertance results to GUI
            app.ExperimentalInertanceEditField.Value = b_exp;
            app.CalculatedInertanceEditField.Value = b_theo;
        end

        % Value changed function: StartDataCollectionButton
        function StartDataCollectionButtonValueChanged(app, event)

            if(app.StartDataCollectionButton.Value)

                serialObj = serialport("COM7", 115200); % Serial port and baud rate from arduino script
                configureTerminator(serialObj, "CR/LF"); % Terminator is a line return
                flush(serialObj); % Clear serial data

                d = daq("ni"); % Create daq object
                ch = addinput(d, "cDAQ1Mod1", "ai0", "Accelerometer"); % Add input for accelerometer
                d.Rate = 1000; % Data collection rate of accelerometer
                ch.Sensitivity = 0.00999; % See accelerometer paper in box

            end

            app.dataCount =[0];
            count = 1;
            app.forceDataArray = [];
            app.forceTimeArray = [];
            app.accDataArray = [];
            app.accTimeArray = [];

            % clf


            start(d, "continuous");


            while app.StartDataCollectionButton.Value

                app.forceDataArray = [app.forceDataArray str2double(readline(serialObj))];
                app.forceTimeArray = [app.forceTimeArray str2double(readline(serialObj))];

                [data, timestamps, ~] = read(d, d.ScansAvailableFcnCount, "OutputFormat", "Matrix");

                app.accTimeArray = [app.accTimeArray timestamps];
                app.accDataArray = [app.accDataArray data];

                plot(app.ForcePlot, app.forceTimeArray,app.forceDataArray, 'k-');
                plot(app.AccPlot, timestamps, data);

            end


            % while(app.StartDataCollectionButton.Value)
            %     % Collect Force Data
            %     app.forceDataArray = [app.forceDataArray, str2double(readline(serialObj))];
            %     app.forceTimeArray = [app.forceTimeArray, str2double(readline(serialObj))];
            %
            %     % Collect Acceleration Data
            %     data = read(d, seconds(0.001));
            %     app.accelDataArray = [app.accelDataArray; data.cDAQ1Mod1_ai0];
            %     app.accelTimeArray = [app.accelTimeArray; app.accelTimeArray(end) + data.Time ];
            %
            %     % Plots
            %     plot(app.ForcePlot, app.forceTimeArray,app.forceDataArray, 'k-');
            %     plot(app.AccPlot, app.accelTimeArray(2:end)', app.accelDataArray', 'b-');
            %
            %     app.dataCount = [app.dataCount count];
            %     count = count + 1;
            %
            % end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccPlot = uiaxes(app.UIFigure);
            title(app.AccPlot, 'Acceleration Data')
            xlabel(app.AccPlot, 'Time (s)')
            ylabel(app.AccPlot, 'Acceleration (m/s^2)')
            zlabel(app.AccPlot, 'Z')
            app.ForcePlot = uiaxes(app.UIFigure);
            title(app.ForcePlot, 'Force Data')
            xlabel(app.ForcePlot, 'Time (s)')
            ylabel(app.ForcePlot, 'Force (N)')
            zlabel(app.ForcePlot, 'Z')
            app.AccelerationMagnitudeLabel = uilabel(app.UIFigure);
            app.AccelerationMagnitudeLabel.Text = {'Acceleration'; 'Magnitude'};
            app.AccelerationMagnitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationMagnitudeEditField.Editable = 'off';
            app.ForceMagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.ForceMagnitudeEditFieldLabel.Text = {'Force'; 'Magnitude'};
            app.ForceMagnitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceMagnitudeEditField.Editable = 'off';
            app.CalculatedInertancekgEditFieldLabel = uilabel(app.UIFigure);
            app.CalculatedInertancekgEditFieldLabel.Text = {'Calculated'; 'Inertance (kg)'};
            app.CalculatedInertanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculatedInertanceEditField.Editable = 'off';
            app.PerformInertanceCalculationButton = uibutton(app.UIFigure, 'push');
            app.PerformInertanceCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateInertance, true);
            app.PerformInertanceCalculationButton.Text = {'Perform'; 'Inertance'; 'Calculation'};
            app.ExperimentalInertanceLabel = uilabel(app.UIFigure);
            app.ExperimentalInertanceLabel.Text = {'Experimental'; 'Inertance (kg)'};
            app.ExperimentalInertanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofGearsEditFieldLabel = uilabel(app.UIFigure);
            app.ofGearsEditFieldLabel.Text = '# of Gears';
            app.numGearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiimEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiimEditFieldLabel.Text = 'Outer Radii (m)';
            app.OuterRadiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiimEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiimEditFieldLabel.Text = 'Inner Radii (m)';
            app.InnerRadiiEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.InerterMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'state');
            app.StartDataCollectionButton.ValueChangedFcn = createCallbackFcn(app, @StartDataCollectionButtonValueChanged, true);
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InerterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end